classdef appi_code_complex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GeneratePlotButton             matlab.ui.control.Button
        PowerButton                    matlab.ui.control.Button
        EnergyButton                   matlab.ui.control.Button
        EnterthepowerofdenominatorifanyEditField  matlab.ui.control.NumericEditField
        EnterthepowerofdenominatorifanyEditFieldLabel  matlab.ui.control.Label
        EnteronlynumbersLabel_2        matlab.ui.control.Label
        EnteronlynumbersLabel          matlab.ui.control.Label
        EnteronlynumbersLabel_3        matlab.ui.control.Label
        EnterthedenominatorofthesignalifanyEditField  matlab.ui.control.NumericEditField
        EnterthedenominatorofthesignalifanyEditFieldLabel  matlab.ui.control.Label
        EnterthepowerofthesignalifanyEditField  matlab.ui.control.NumericEditField
        EnterthepowerofthesignalifanyEditFieldLabel  matlab.ui.control.Label
        EnterthesignalperiodEditField  matlab.ui.control.NumericEditField
        EnterthesignalperiodEditFieldLabel  matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        EnterthefunctionofthesignalEditField_2  matlab.ui.control.EditField
        EnterthefunctionofthesignalEditField_2Label  matlab.ui.control.Label
        EnteronlynumberspiincludedfortrigonometricfunctionsLabel  matlab.ui.control.Label
        EnteronlysincosurdeltaalgebraLabel  matlab.ui.control.Label
        EntertheendingsamplingperiodEditField  matlab.ui.control.NumericEditField
        EntertheendingsamplingperiodEditFieldLabel  matlab.ui.control.Label
        EnterthestartingsamplingperiodEditField  matlab.ui.control.NumericEditField
        EnterthestartingsamplingperiodEditFieldLabel  matlab.ui.control.Label
        EnterthenumericconstantofthesignalEditField  matlab.ui.control.NumericEditField
        EnterthenumericconstantofthesignalEditFieldLabel  matlab.ui.control.Label
        EnergyPowerofDTSSignalsLabel   matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        InputsignalEditField           matlab.ui.control.EditField
        InputsignalEditFieldLabel      matlab.ui.control.Label
        ProjectGroupNumber07Label      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function sintButtonPushed(app, event)

        end

        % Value changed function: InputsignalEditField
        function InputsignalEditFieldValueChanged(app, event)

        end

        % Callback function
        function EnterfunctionofthesignalEditFieldValueChanged(app, event)

        end

        % Callback function
        function EnternumericconstantifanyofthesignalEditFieldValueChanged(app, event)

        end

        % Callback function
        function EnterphaseangleincaseoftrigonometricsignalEditFieldValueChanged(app, event)

        end

        % Value changed function: 
        % EnterthefunctionofthesignalEditField_2
        function EnergyButtonPushed(app, event)

        end

        % Button pushed function: EnergyButton
        function EnergyButtonPushed2(app, event)
            func = app.EnterthefunctionofthesignalEditField_2.Value;
            inp = app.EnterthenumericconstantofthesignalEditField.Value;
            den = app.EnterthedenominatorofthesignalifanyEditField.Value;
            lwl = app.EnterthestartingsamplingperiodEditField.Value;
            upl = app.EntertheendingsamplingperiodEditField.Value;
            pwrn = app.EnterthepowerofthesignalifanyEditField.Value;
            pwrd = app.EnterthepowerofdenominatorifanyEditField.Value;

            val = inp * pi;
            tot = 0;

            if (func == "sin")
                app.EditField_3.Value = 1/0;
       
            elseif func == "cos"
                app.EditField_3.Value = 1/0;

            elseif func == "algebra"
                app.EditField_3.Value = 1/0;

            elseif func == "u"
                app.EditField_3.Value = 1/0;
                

            elseif (func == "r" )
                app.EditField_3.Value = 1/0;

            elseif (func == "delta" )
                app.EditField_3.Value = double(inp*inp);
            end
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            func = app.EnterthefunctionofthesignalEditField_2.Value;
            inp = app.EnterthenumericconstantofthesignalEditField.Value;
            den = app.EnterthedenominatorofthesignalifanyEditField.Value;
            lwl = app.EnterthestartingsamplingperiodEditField.Value;
            upl = app.EntertheendingsamplingperiodEditField.Value;
            pwrn = app.EnterthepowerofthesignalifanyEditField.Value;
            pwrd = app.EnterthepowerofdenominatorifanyEditField.Value;

            val = inp * pi;
            tot = 0;
            syms lims;

            if (func == "sin")
                
                app.EditField_2.Value = (inp*inp)/2;

            elseif func == "cos"
                app.EditField_2.Value = (inp*inp)/2;

            elseif func == "algebra"
                app.EditField_2.Value = inp*inp;
                
            elseif func == "u" 
                for  i = 0 : 1.0 : (upl-1)
                    tot = tot + 1;
                    if (i == (upl-1))
                        tot = tot/upl;
                        inpu = double(limit (tot, lims, inf));
                        app.EditField_2.Value = inpu;
                    end
                end

            elseif (func == "r" )
                app.EditField_2.Value = 1/0;

            elseif (func == "delta" )
                app.EditField_2.Value = 0;
            end
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            a = double(app.EnterthestartingsamplingperiodEditField.Value);
            c = double(app.EntertheendingsamplingperiodEditField.Value);
            b = double(app.EnterthesignalperiodEditField.Value);
            x = [a:b:c];
            A = double(app.EnterthenumericconstantofthesignalEditField.Value);
            power_sig = app.EnterthepowerofthesignalifanyEditField.Value;
            deno_sig = app.EnterthedenominatorofthesignalifanyEditField.Value;
            deno_sig_power = app.EnterthepowerofdenominatorifanyEditField.Value;
            y = zeros(1,length(x));
            global input_sig;
            input_sig = strcat("[",num2str(app.EnterthenumericconstantofthesignalEditField.Value),app.EnterthefunctionofthesignalEditField_2.Value,"^",num2str(app.EnterthepowerofthesignalifanyEditField.Value),"(x)]",num2str(app.EnterthedenominatorofthesignalifanyEditField.Value),"^",num2str(app.EnterthepowerofdenominatorifanyEditField.Value));
            
            
            func = app.EnterthefunctionofthesignalEditField_2.Value;
            if (func == "sin")
                for i = 1:(length(x))
                    y(i) = (A *(sin(x(i))^power_sig))/deno_sig.^deno_sig_power;
                end

            elseif func == "cos"
                for i = 1:(length(x))
                    y(i) = (A *(cos(x(i))^power_sig))/deno_sig.^deno_sig_power;
                end

            elseif func == "algebra"
                for i = 1:(length(x))
                    y(i) = A;
                    input_sig = num2str(A);
                end               

            elseif func == "u"
                for i = 1:(length(x))
                    if x(i)>=0 
                        y(i) = (A/deno_sig^deno_sig_power);
                    else
                        y(i) = 0;
                    end
                    
                end
                
            elseif (func == "r" )
                 for i = 1:(length(x))
                    if x(i)>=0 
                        y(i) = (A*x(i)/deno_sig^deno_sig_power);
                    else
                        y(i) = 0;
                    end
                    
                 end                

            elseif (func == "delta" )
                 for i = 1:(length(x))
                    if x(i)==0 
                        y(i) = 1;
                    else
                        y(i) = 0;
                    end
                    
                end
                
            end
            app.InputsignalEditField.Value = input_sig;
            stem(x,y,"MarkerFaceColor","red");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProjectGroupNumber07Label = uilabel(app.UIFigure);
            app.ProjectGroupNumber07Label.Text = 'Project Group Number: 07';
            app.InputsignalEditFieldLabel = uilabel(app.UIFigure);
            app.InputsignalEditFieldLabel.Text = 'Input signal';
            app.InputsignalEditField = uieditfield(app.UIFigure, 'text');
            app.InputsignalEditField.ValueChangedFcn = createCallbackFcn(app, @InputsignalEditFieldValueChanged, true);
            app.InputsignalEditField.Tag = 'screen';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EnergyPowerofDTSSignalsLabel = uilabel(app.UIFigure);
            app.EnergyPowerofDTSSignalsLabel.Text = 'Energy & Power of DTS Signals';
            app.EnterthenumericconstantofthesignalEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthenumericconstantofthesignalEditFieldLabel.Text = 'Enter the numeric constant of the signal';
            app.EnterthenumericconstantofthesignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterthenumericconstantofthesignalEditField.Value = 1;
            app.EnterthestartingsamplingperiodEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthestartingsamplingperiodEditFieldLabel.Text = 'Enter the starting sampling period ';
            app.EnterthestartingsamplingperiodEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterthestartingsamplingperiodEditField.Value = 1;
            app.EntertheendingsamplingperiodEditFieldLabel = uilabel(app.UIFigure);
            app.EntertheendingsamplingperiodEditFieldLabel.Text = 'Enter the ending sampling period ';
            app.EntertheendingsamplingperiodEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntertheendingsamplingperiodEditField.Value = 4;
            app.EnteronlysincosurdeltaalgebraLabel = uilabel(app.UIFigure);
            app.EnteronlysincosurdeltaalgebraLabel.Text = {'Enter only -'; '''sin'' / ''cos'' / ''u'' / ''r'' / ''delta'' / ''algebra'''};
            app.EnteronlynumberspiincludedfortrigonometricfunctionsLabel = uilabel(app.UIFigure);
            app.EnteronlynumberspiincludedfortrigonometricfunctionsLabel.Text = {'Enter only -'; '(numbers)'; 'pi included for trigonometric functions'};
            app.EnterthefunctionofthesignalEditField_2Label = uilabel(app.UIFigure);
            app.EnterthefunctionofthesignalEditField_2Label.Text = 'Enter the function of the signal';
            app.EnterthefunctionofthesignalEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EnterthefunctionofthesignalEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnergyButtonPushed, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EnterthesignalperiodEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthesignalperiodEditFieldLabel.Text = 'Enter the signal period ';
            app.EnterthesignalperiodEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterthesignalperiodEditField.Value = 1;
            app.EnterthepowerofthesignalifanyEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthepowerofthesignalifanyEditFieldLabel.Text = 'Enter the power of the signal (if any)';
            app.EnterthepowerofthesignalifanyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterthepowerofthesignalifanyEditField.Value = 1;
            app.EnterthedenominatorofthesignalifanyEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthedenominatorofthesignalifanyEditFieldLabel.Text = 'Enter the denominator of the signal (if any)';
            app.EnterthedenominatorofthesignalifanyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterthedenominatorofthesignalifanyEditField.Value = 1;
            app.EnteronlynumbersLabel_3 = uilabel(app.UIFigure);
            app.EnteronlynumbersLabel_3.Text = {'Enter only -'; '(numbers)'};
            app.EnteronlynumbersLabel = uilabel(app.UIFigure);
            app.EnteronlynumbersLabel.Text = {'Enter only -'; '(numbers)'};
            app.EnteronlynumbersLabel_2 = uilabel(app.UIFigure);
            app.EnteronlynumbersLabel_2.Text = {'Enter only -'; '(numbers)'};
            app.EnterthepowerofdenominatorifanyEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthepowerofdenominatorifanyEditFieldLabel.Text = 'Enter the power of denominator (if any)';
            app.EnterthepowerofdenominatorifanyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterthepowerofdenominatorifanyEditField.Value = 1;
            app.EnergyButton = uibutton(app.UIFigure, 'push');
            app.EnergyButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyButtonPushed2, true);
            app.EnergyButton.Text = 'Energy';
            app.PowerButton = uibutton(app.UIFigure, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = 'Power';
            app.GeneratePlotButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appi_code_complex

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end